classdef EBBPrac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        SaveDirEditField           matlab.ui.control.EditField
        SaveDirEditFieldLabel      matlab.ui.control.Label
        StopControllingButton      matlab.ui.control.Button
        StartControllingButton     matlab.ui.control.Button
        DataSaveEditField          matlab.ui.control.EditField
        DataSaveEditFieldLabel     matlab.ui.control.Label
        SamplingtoofastLabel       matlab.ui.control.Label
        UpdateChannelsButton       matlab.ui.control.Button
        StartMeasuringButton       matlab.ui.control.StateButton
        StopButton                 matlab.ui.control.StateButton
        DisturbanceEditField       matlab.ui.control.NumericEditField
        DisturbanceEditFieldLabel  matlab.ui.control.Label
        SetpointEditField          matlab.ui.control.NumericEditField
        SetpointEditFieldLabel     matlab.ui.control.Label
        DEditField                 matlab.ui.control.NumericEditField
        DEditFieldLabel            matlab.ui.control.Label
        IEditField                 matlab.ui.control.NumericEditField
        IEditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        Tab2                       matlab.ui.container.Tab
        BaudRateEditField          matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel     matlab.ui.control.Label
        COMPortEditField           matlab.ui.control.EditField
        COMPortEditFieldLabel      matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        %Live lines 1 and 2 are for the temperature measurements.
        liveLine1
        liveLine2
        %Live lines 3 and 4 are for the PWM signals.
        liveLine3
        liveLine4
        %Live line for setpoint tracking.
        liveLine5
        P
        I
        D
        setpoint
        disturbance
        start_controlling
        output
        capturing
        COM_port
        baud_rate
        window_size
        esp32
        data_save_name % Description
        data_save_dir
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            app.P = app.PEditField.Value;
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            app.I = app.IEditField.Value;
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            app.D = app.DEditField.Value;
        end

        % Value changed function: SetpointEditField
        function SetpointEditFieldValueChanged(app, event)
            app.setpoint = app.SetpointEditField.Value;
        end

        % Value changed function: DisturbanceEditField
        function DisturbanceEditFieldValueChanged(app, event)
            app.disturbance = app.DisturbanceEditField.Value;
        end

        % Callback function
        function DutyCycleEditFieldValueChanged(app, event)
            app.start_controlling = app.DutyCycleEditField.Value;
        end

        % Callback function
        function OutputDropDownValueChanged(app, event)
            app.output = str2double(app.OutputDropDown.Value);
        end

        % Value changed function: StartMeasuringButton
        function StartMeasuringButtonValueChanged(app, event)
            app.StartMeasuringButton.Visible = "off";
            app.StopButton.Visible = "on";
            app.UpdateChannelsButton.Visible = "on";
            app.capturing = 1;

            %Call all of the other functions to make sure their values are
            %captured.
            app.PEditFieldValueChanged();
            app.IEditFieldValueChanged();
            app.DEditFieldValueChanged();
            app.SetpointEditFieldValueChanged();
            app.DisturbanceEditFieldValueChanged();
            app.BaudRateEditFieldValueChanged();
            app.COMPortEditFieldValueChanged();
            app.DataSaveEditFieldValueChanged();

            if isempty(app.esp32)
                app.esp32 = serialport(app.COM_port, app.baud_rate);
                configureTerminator(app.esp32, "LF");
            end
            flush(app.esp32);

            pause(1);

            %Make sure all info is read into esp.
            writeline(app.esp32, sprintf("%d, %.10f", 0, app.P)); %
            writeline(app.esp32, sprintf("%d, %.10f", 1, app.I)); %
            writeline(app.esp32, sprintf("%d, %.10f", 2, app.D)); %
            writeline(app.esp32, sprintf("%d, %.10f", 3, app.setpoint)); %
            writeline(app.esp32, sprintf("%d, %.10f", 4, app.disturbance)); %
            writeline(app.esp32, sprintf("%d, %d", 5, 0)); %
            writeline(app.esp32, sprintf("%d, %.5f", 7, app.capturing)); %

            %Clear axes.
            cla(app.UIAxes);
            cla(app.UIAxes_2);

            %Plotting axes for the temperature measurements.
            app.liveLine1 = animatedline(app.UIAxes, 'Color', 'r', 'DisplayName', 'Temperature 1 Output');
            
            % app.liveLine2 = animatedline(app.UIAxes, 'Color', 'b');

            %Plotting axes for the PWM signals.
            app.liveLine3 = animatedline(app.UIAxes_2, 'Color', 'r', 'DisplayName', 'Control Input');
            app.liveLine4 = animatedline(app.UIAxes_2, 'Color', 'b', 'DisplayName', 'Disturbance');
            legend(app.UIAxes_2, 'Location', 'best');

            %Plotting axis for setpoint tracking. On the same axis as the
            %temperatures.
            app.liveLine5 = animatedline(app.UIAxes, 'LineStyle', ':', 'Color', 'g', LineWidth=2, DisplayName='Setpoint');
            legend(app.UIAxes, 'Location', 'best');

            app.window_size = 30; %seconds

            %Define data csv that we will safe to.
            app.SaveDirEditFieldValueChanged();
            mkdir(app.data_save_dir);
            filepath = fullfile(app.data_save_dir, app.data_save_name + ".csv");
            fid = fopen(filepath, 'wt');

            %Running average arrays for the PWM signals.
            N_average = 30;
            pwm1_points = zeros(N_average);
            pwm2_points = zeros(N_average);

            while(app.capturing)
                if app.esp32.NumBytesAvailable > 0
                    rawData = readline(app.esp32);
                    if isstring(rawData)
                        data = str2double(split(rawData, ','));
                        if length(data) > 4
                            data(5) = data(5)/1000;
                        else
                            continue;
                        end
                    end

                    if ~isnan(data(1)) && (length(data) == 6)
                        %Plot temperature data.
                        addpoints(app.liveLine1, data(5), data(1));
                        % addpoints(app.liveLine2, data(5), data(2));
                        %Plot setpoint.
                        addpoints(app.liveLine5, data(5), app.setpoint);
                        %Resize axis.
                        xlim(app.UIAxes, [data(5) - app.window_size, data(5)]);

                        %Update running averages for PWM signals.
                        pwm1_points = [pwm1_points(2:end), data(3)];
                        pwm2_points = [pwm2_points(2:end), data(4)];

                        %Plot PWM signals.
                        addpoints(app.liveLine3, data(5), mean(pwm1_points));
                        addpoints(app.liveLine4, data(5), mean(pwm2_points));
                        %Resize axis.
                        xlim(app.UIAxes_2, [data(5) - app.window_size, data(5)]);

                        %Write data to file.
                        fprintf(fid, rawData);

                        if data(end) == 0
                            app.SamplingtoofastLabel.Visible = "on";
                        else
                            app.SamplingtoofastLabel.Visible = "off";
                        end
                    end

                    drawnow limitrate
                end
            end

            %Close file we are saving to.
            fclose(fid);

            %Send stop signal.
            writeline(app.esp32, sprintf("%d, %.2f", 7, app.capturing));

            clear app.esp32;
        end

        % Value changed function: COMPortEditField
        function COMPortEditFieldValueChanged(app, event)
            app.COM_port = app.COMPortEditField.Value;
        end

        % Value changed function: BaudRateEditField
        function BaudRateEditFieldValueChanged(app, event)
            app.baud_rate = app.BaudRateEditField.Value;
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            app.capturing = false;
            app.start_controlling = false;

            app.StopButton.Visible = "off";
            app.UpdateChannelsButton.Visible = "off";
            app.StartMeasuringButton.Visible = "on";
            app.StartControllingButton.Visible = "on";
            app.StopControllingButton.Visible = "off";
        end

        % Button pushed function: UpdateChannelsButton
        function UpdateChannelsButtonPushed(app, event)
            app.PEditFieldValueChanged();
            app.IEditFieldValueChanged();
            app.DEditFieldValueChanged();
            app.SetpointEditFieldValueChanged();
            app.DisturbanceEditFieldValueChanged();

            writeline(app.esp32, sprintf("%d, %.2f", 5, app.start_controlling));
            writeline(app.esp32, sprintf("%d, %.10f", 0, app.P)); %
            writeline(app.esp32, sprintf("%d, %.10f", 1, app.I)); %
            writeline(app.esp32, sprintf("%d, %.10f", 2, app.D)); %
            writeline(app.esp32, sprintf("%d, %.10f", 3, app.setpoint)); %
            writeline(app.esp32, sprintf("%d, %.10f", 4, app.disturbance)); %
        end

        % Value changed function: DataSaveEditField
        function DataSaveEditFieldValueChanged(app, event)
            app.data_save_name = app.DataSaveEditField.Value;
            
        end

        % Button pushed function: StartControllingButton
        function StartControllingButtonPushed(app, event)
            app.start_controlling = 1;
            app.UpdateChannelsButtonPushed();

            app.StartControllingButton.Visible = "off";
            app.StopControllingButton.Visible = "on";
        end

        % Button pushed function: StopControllingButton
        function StopControllingButtonPushed(app, event)
            app.start_controlling = 0;
            app.UpdateChannelsButtonPushed();

            app.StartControllingButton.Visible = "on";
            app.StartControllingButton.Visible = "off";
        end

        % Value changed function: SaveDirEditField
        function SaveDirEditFieldValueChanged(app, event)
            app.data_save_dir = app.SaveDirEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {449, 449};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.PEditFieldLabel = uilabel(app.Tab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.Tab, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.PEditField.Value = 0.067424;
            app.IEditFieldLabel = uilabel(app.Tab);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.Tab, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.IEditField.Value = 0.0016631;
            app.DEditFieldLabel = uilabel(app.Tab);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.Tab, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.DEditField.Value = 0.68337;
            app.SetpointEditFieldLabel = uilabel(app.Tab);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField = uieditfield(app.Tab, 'numeric');
            app.SetpointEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointEditFieldValueChanged, true);
            app.SetpointEditField.Value = 35;
            app.DisturbanceEditFieldLabel = uilabel(app.Tab);
            app.DisturbanceEditFieldLabel.Text = 'Disturbance';
            app.DisturbanceEditField = uieditfield(app.Tab, 'numeric');
            app.DisturbanceEditField.ValueChangedFcn = createCallbackFcn(app, @DisturbanceEditFieldValueChanged, true);
            app.StopButton = uibutton(app.Tab, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.StartMeasuringButton = uibutton(app.Tab, 'state');
            app.StartMeasuringButton.ValueChangedFcn = createCallbackFcn(app, @StartMeasuringButtonValueChanged, true);
            app.StartMeasuringButton.Text = 'Start Measuring';
            app.UpdateChannelsButton = uibutton(app.Tab, 'push');
            app.UpdateChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateChannelsButtonPushed, true);
            app.UpdateChannelsButton.Visible = 'off';
            app.UpdateChannelsButton.Text = 'Update Channels';
            app.SamplingtoofastLabel = uilabel(app.Tab);
            app.SamplingtoofastLabel.Visible = 'off';
            app.SamplingtoofastLabel.Text = 'Sampling too fast!';
            app.DataSaveEditFieldLabel = uilabel(app.Tab);
            app.DataSaveEditFieldLabel.Text = 'Data Save';
            app.DataSaveEditField = uieditfield(app.Tab, 'text');
            app.DataSaveEditField.ValueChangedFcn = createCallbackFcn(app, @DataSaveEditFieldValueChanged, true);
            app.DataSaveEditField.Value = 'PID_Ref_Track_S1_V0';
            app.StartControllingButton = uibutton(app.Tab, 'push');
            app.StartControllingButton.ButtonPushedFcn = createCallbackFcn(app, @StartControllingButtonPushed, true);
            app.StartControllingButton.Text = 'Start Controlling';
            app.StopControllingButton = uibutton(app.Tab, 'push');
            app.StopControllingButton.ButtonPushedFcn = createCallbackFcn(app, @StopControllingButtonPushed, true);
            app.StopControllingButton.Visible = 'off';
            app.StopControllingButton.Text = 'Stop Controlling';
            app.SaveDirEditFieldLabel = uilabel(app.Tab);
            app.SaveDirEditFieldLabel.Text = 'Save Dir';
            app.SaveDirEditField = uieditfield(app.Tab, 'text');
            app.SaveDirEditField.ValueChangedFcn = createCallbackFcn(app, @SaveDirEditFieldValueChanged, true);
            app.SaveDirEditField.Value = 'DataTest';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.COMPortEditFieldLabel = uilabel(app.Tab2);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.Tab2, 'text');
            app.COMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMPortEditFieldValueChanged, true);
            app.COMPortEditField.Value = '/dev/ttyUSB0';
            app.BaudRateEditFieldLabel = uilabel(app.Tab2);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.Tab2, 'numeric');
            app.BaudRateEditField.ValueChangedFcn = createCallbackFcn(app, @BaudRateEditFieldValueChanged, true);
            app.BaudRateEditField.Value = 1152000;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Temperature output')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Temperature (Celcius)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Control and Disturbance inputs')
            xlabel(app.UIAxes_2, 'Time (seconds)')
            ylabel(app.UIAxes_2, 'Average control input (V)')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBBPrac

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end